classdef GUI_Myelin_defect_annotation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApplyButton                     matlab.ui.control.Button
        TransparencyField               matlab.ui.control.NumericEditField
        TransparencyLabel               matlab.ui.control.Label
        ConfidenceField                 matlab.ui.control.NumericEditField
        ConfidenceEditFieldLabel        matlab.ui.control.Label
        MinintensitySpinner             matlab.ui.control.Spinner
        PressSpacetoreturntofullFOVLabel  matlab.ui.control.Label
        ProcessImageButton              matlab.ui.control.Button
        AdjustContrastButton            matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ImageAxes                       matlab.ui.control.UIAxes
        ProcessedImageTab               matlab.ui.container.Tab
        Processed_ImageAxes             matlab.ui.control.UIAxes
        DefectViewerTab                 matlab.ui.container.Tab
        MakebgbButton                   matlab.ui.control.Button
        DownzsButton                    matlab.ui.control.Button
        UpzwButton                      matlab.ui.control.Button
        RemovedefectrButton             matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        PreviousaButton                 matlab.ui.control.Button
        NextdButton                     matlab.ui.control.Button
        Cropped_Defect_ImageAxes        matlab.ui.control.UIAxes
        Status                          matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        ClassesTab                      matlab.ui.container.Tab
        DeleteCurrentClassButton        matlab.ui.control.Button
        EditCurrentClassButton          matlab.ui.control.Button
        ShortcutEditField               matlab.ui.control.EditField
        ShortcutLabel                   matlab.ui.control.Label
        ClassshapeDropDown              matlab.ui.control.DropDown
        ClassshapeLabel                 matlab.ui.control.Label
        ColorSelectorBtn                matlab.ui.control.Button
        ClassnameEditField              matlab.ui.control.EditField
        ClassnameLabel                  matlab.ui.control.Label
        AddClassButton                  matlab.ui.control.Button
        ClassnamesListBox               matlab.ui.control.ListBox
        ClassnamesListBoxLabel          matlab.ui.control.Label
        CurrentClassLbl                 matlab.ui.control.Label
        CurrentClassLabel               matlab.ui.control.Label
        Layers                          matlab.ui.container.Tab
        TextTransparencyField           matlab.ui.control.NumericEditField
        TransparencyLabel_2             matlab.ui.control.Label
        FilenameStemLabel               matlab.ui.control.Label
        NumDefectsLabel                 matlab.ui.control.Label
        NumDefectsAnnLabel              matlab.ui.control.Label
        NumDefectsGTLabel               matlab.ui.control.Label
        DLModelPredictionsCheckBox      matlab.ui.control.CheckBox
        GroundTruthCheckBox             matlab.ui.control.CheckBox
        LayersLabel                     matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        GridAnnotationCheckBox          matlab.ui.control.CheckBox
        ProcessingFunctionsDropDown     matlab.ui.control.DropDown
        ProcessingFunctionsDropDownLabel  matlab.ui.control.Label
        AutoProcessImagesCheckBox       matlab.ui.control.CheckBox
        IncludeSubfoldersCheckBox       matlab.ui.control.CheckBox
        ImageListDropDown               matlab.ui.control.DropDown
        ImageListDropDownLabel          matlab.ui.control.Label
        DeepLearningModelDropDown       matlab.ui.control.DropDown
        DeepLearningModelDropDownLabel  matlab.ui.control.Label
        SaveSettingsButton              matlab.ui.control.Button
        MinObjectSizepixelsEditField    matlab.ui.control.NumericEditField
        MinObjectSizepixelsEditField_2Label  matlab.ui.control.Label
        AnnotatorNameEditField          matlab.ui.control.EditField
        AnnotatorNameEditFieldLabel     matlab.ui.control.Label
        ScrollSpeedEditField            matlab.ui.control.NumericEditField
        ScrollSpeed110Label             matlab.ui.control.Label
        GridAnnotationTab               matlab.ui.container.Tab
        WindowOverlapEditField          matlab.ui.control.NumericEditField
        WindowOverlapLabel              matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ZoomButton                      matlab.ui.control.StateButton
        RandomizeImagefolderSwitch      matlab.ui.control.Switch
        RandomizeImagefolderSwitchLabel  matlab.ui.control.Label
        HideAllRectanglesSwitch         matlab.ui.control.Switch
        HideAllRectanglesSwitchLabel    matlab.ui.control.Label
        PreviousButton                  matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        ImportFolderButton              matlab.ui.control.Button
        minmaxintensitySpinner          matlab.ui.control.Spinner
        MaxintensityLabel               matlab.ui.control.Label
        SliceSlider                     matlab.ui.control.Slider
        ClearAllButton                  matlab.ui.control.Button
        SliceSliderLabel                matlab.ui.control.Label
        FileIOPanel                     matlab.ui.container.Panel
        SaveFileFormatDropDown          matlab.ui.control.DropDown
        SaveFileFormatLabel             matlab.ui.control.Label
        FinishSaveButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        RandTimer;               % Timer object
        % All for VIEWING results
        filename_annotations; % the filename for the DL model predictions
        filename_gTruth; % the filename for the gTruth predictions
        num_defects_gTruth; % the number of defects in the ground truth
        num_defects_annotations; % the number of defects in the DL prediction
        filename_stem % the filename stem
    end
    
    properties (Access = public)
        time; % handle the timer time
        executableFolder; % main folder for the application (so we know where to find all extra functions and DL models
        logo;

        %defect viewer info
        defect_idx = 0;
        text_transparency = 0;
        defect_z = 15;

        % image information
        image_handle; % Handle for currently loaded image
        processed_image_handle; % Handle for currently loaded image after processing
        image_path;
        image_folder;
        image_list; % list of strings for all the iamges when you load a folder
        file_list;
        fds;
        current_filename;
        annotated_list; % list of all the already annotated files (indexs)
        unannotated_counter; %$ keep track of which number we are at for annotated nad unannotated
        annotated_counter; %$ keep track of which number we are at for annotated nad unannotated
        unannotated_list; %$ keep track of which number we are at for annotated nad unannotated
        main_folder; %Folder path for where all images are
        image_loaded = 0;

        % handles for images and viewing
        image_size = [0, 0]; % size of image (in pixels) on the screen
        stack_size; % if we have a z stack handle how many planes we have and stuff
        image_number = 1;
        imds; % datastore ot hold all the images (if loading a folder)
        mat_ds; % .mat file datastore for all the corresponding mat files
        stack_number = 1;
        randomizer;
        hImage; % used for displaying the image (helps to draw rectangles on this image type
        scroll_amount = 1; % how many planes that you can use to scroll through a image stack
        
        rect_handle;
        X_rect;
        Y_rect;
        %comment
        test=1;
        
        % handle drawing the ROIs (bounding boxes)
        patchx = []; % handle for the rectangle Bounding Box (x-coordinates)
        patchy = []; % handle for the rectangle Bounding Box (y-coordinates)
        patchx_tmp; % handle for the rectangle Bounding Box (x-coordinates)
        patchy_tmp; % handle for the rectangle Bounding Box (y-coordinates)
        patch_handle = []; % handle for actually plotting the patch coordinates
        hTool; % handle for adjusting contrast
        

        % used to annotate multiple planes when holding down button
        starting_z;
        current_z; 

        % grid annotation
        gridstep;
        grid_idx = [1,1];
        num_blocksx = 0;
        num_blocksy = 0;

        % save array is strcutured as follows (to make it easiest to patch
        % all bounding boxes and keep track of everything
        %%%%%%%
        save_array; % cell array or struct to store all the data we need
        annotated_filepath_arr = [];
        annotator_name_arr = [];
        classname_arr = [];
        color_arr = [];
        z_plane_arr= [];
        save_array_bb = []; %used to make plotting of the rectangles faster
        conf_arr = []; %saves the conf scores of each bounding box
        class_list = {'Default', [1 0 0], 'Rectangle', '1', 'Default | Shortcut: "1"'}; % list of all classes to choose from (and index from)
        class_struct; %hold all information for each class so it can be easily saved and read

        % making a copy of the above properties which store all the data
        % values for the GROUND TRUTH ANNOTATIONS -- for a viewing scenario
        %%%%%%%
        save_array_2; % cell array or struct to store all the data we need
        annotated_filepath_arr_2 = [];
        annotator_name_arr_2 = [];
        classname_arr_2 = [];
        color_arr_2 = [];
        z_plane_arr_2 = [];
        save_array_bb_2 = []; %used to make plotting of the rectangles faster 
        load_gTruth_sentinel = false; % a flag of whether to load the gTruth annotations. Default is false
        load_annotations_sentinel = true;

        % list of all possible shortcuts that people could use 
        shortcut_list = ['1','2','3','4','5','6','7','8','9','0','q','w','e','r','t','y','u','i','o','p',...
            'a','s','d','f','g','h','j','k','l','z','x','c','v','b','n','m']

        shortcut_current; %the list of the current objects (shortcuts for them)
        current_class = 'Default'; % get the current classname/index
        current_class_ind; % get the current classname/index
        current_color = [1 0 0]; % get the current color for the class (default of Red)
        current_shape = 'Rectangle'; % cuyrrent type of annotation we wanna do
        current_bb = [0 0 0 0];
        current_patch = [];
        current_shortcut; % current keyboard shortcut
        color_new; % new color for new class
        classname_new; % new classname for new class
        %%%%%%%

        %Bounding box storing data
        annot_counter = 1;
        zoom_limitsx;
        zoom_limitsy;
        img_fstack;

        % Flags
        button_clicked = 0; %track if there is a current button click
        zoom_mode = 0; % check if the zoom mode has been turned on
        no_mat_files_flag = 0; % flag if we have bounding box data loaded
        ROI_selected_index; % index of the patch that we are currently in (used to select/delete an ROI)
        folder_loaded = 0; % check if we loaded in multiple images

        %%% Settings Array (Cell array to store all values %%%
        settings_default = {'Annotator Name', 'Default';
            'Scroll Speed', 3; 'Save File Format', 1
            'classes', 'Default'
            'Class Color', ones(3)};
        settings_data;
    end

    methods (Access = private)
        function RandTimerFcn(app,~,~)
            % Generate a random number
            app.time = app.time + 1;
            app.TimeLbl.Text = num2str(app.time);
        end 
        
        function update_image(app)
            app.hImage = image(app.ImageAxes, zeros(size(app.image_handle,2),size(app.image_handle,1),size(app.image_handle,3)), 'HitTest','off');
            
            colormap(app.ImageAxes, 'gray')
            app.hImage.CDataMapping = 'scaled';
            
            app.ImageAxes.XLim = app.zoom_limitsx;
            app.ImageAxes.YLim = app.zoom_limitsy;
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            
            %set aspect ratio of plot (for proper viewing of image)
            pbaspect(app.ImageAxes,[size(app.image_handle,2),size(app.image_handle,1),1])
            
            if size(app.image_handle,4)>1
%                 disp('Z-stack')
                update_image_plane(app);
            else
                size(app.image_handle)
                app.hImage.CData = im2double(app.image_handle);
            end
        end

         function update_processed_image(app)
            max_int = app.minmaxintensitySpinner.Value/100;
            min_int = app.MinintensitySpinner.Value/100;
            
            %This isn't actually doing anything. All of the contrast change
            %is being done in update_image --> update_image_plane
            imshow(imadjust(app.img_fstack, [min_int max_int]), 'Parent',app.Processed_ImageAxes);
        end


        function update_image_plane(app)

            if size(app.image_handle,3)>1
                tmp_img=app.image_handle(:,:,:,app.stack_number);
                max_int = app.minmaxintensitySpinner.Value/100;
                min_int = app.MinintensitySpinner.Value/100;
                img = imadjust(tmp_img, [min_int max_int]);
                %app.hImage.CData = app.image_handle(:,:,:,app.stack_number);
                app.hImage.CData = img;
                
            else
                app.hImage.CData = app.image_handle(:,:,app.stack_number);
                max_int = app.minmaxintensitySpinner.Value/100;
                min_int = app.MinintensitySpinner.Value/100;
                img = imadjust(app.hImage.CData, [min_int max_int]);
                %app.hImage.CData = app.image_handle(:,:,:,app.stack_number);
                app.hImage.CData = img;
            end
            app.SliceSlider.Value = app.stack_number;
        end

        function update_defect_viewer(app, z)
                coord = app.save_array_bb(:,app.defect_idx); % [X,Y,W,H] coordinates format
                margin = 50;
                x1 = coord(2) + round(coord(4)/2) - margin; %x1=clip(x1,1,app.image_size(1)); 
                x2 = coord(2) + round(coord(4)/2) + margin; %x2=clip(x2,1,app.image_size(1));
                y1 = coord(1) + round(coord(3)/2) - margin; %y1=clip(y1,1,app.image_size(1));
                y2 = coord(1) + round(coord(3)/2) + margin; %y2=clip(y2,1,app.image_size(1));

                %tmp_img =  app.image_handle(x1:x2, y1:y2, :, z); 
                tmp_img = cropWithFill(app, app.image_handle(:,:,:,z), x1:x2, y1:y2, 255);
                %max_int = app.minmaxintensitySpinner.Value/100;
                %min_int = app.MinintensitySpinner.Value/100;
                n=3;
                Idouble = im2double(tmp_img); 
                avg = mean2(Idouble)*4;
                sigma = std2(Idouble);
                %Adjust the contrast based on the standard deviation.
                
                output = imadjust(tmp_img,[0 min(avg+n*sigma,1.0)],[]);
                %tmp_img = im2uint8(tmp_img);
                %output = imadjust(tmp_img, [min_int max_int]);
                imshow(output, 'Parent',app.Cropped_Defect_ImageAxes);


                w = coord(4); h = coord(3);
                x0 = margin - w/2; y0 = margin - h/2;
                tmp_x = [y0; y0+h; y0+h; y0];
                tmp_y = [x0+w; x0+w; x0; x0];
                
                tmp_patch = patch(tmp_x,tmp_y, 'red', 'FaceColor', 'none', 'EdgeColor', app.color_arr(:,app.defect_idx(1))', 'Parent', app.Cropped_Defect_ImageAxes, 'EdgeAlpha',1);


        end

        function cropped = cropWithFill(app, img, rowRange, colRange, fillColor)
            % Input validation
            if nargin < 4
                fillColor = 255; % Default to white fill (use 0 for black)
            end
            
            % Get original image dimensions
            [height, width, channels] = size(img);
            
            % Create output image filled with specified color
            if channels == 1
                % Grayscale image
                cropped = fillColor * ones(length(rowRange), length(colRange), 'like', img);
            else
                % RGB image
                cropped = fillColor * ones(length(rowRange), length(colRange), channels, 'like', img);
            end
            
            % Calculate valid indices
            validRows = rowRange >= 1 & rowRange <= height;
            validCols = colRange >= 1 & colRange <= width;
            
            % Get valid ranges
            validRowRange = rowRange(validRows);
            validColRange = colRange(validCols);
            
            % Copy valid portion of original image
            cropped(validRows, validCols, :) = img(validRowRange, validColRange, :);
        end


        function reset_properties(app)
            %maybe need to add these
%             app.image_size = [0, 0]; % size of image (in pixels) on the screen
%             app.image_number = 1;
%             app.class_list = {'Default', [1 0 0], 'Rectangle', '1', 'Default | Shortcut: "1"'}; % list of all classes to choose from (and index from)
%             app.class_struct; %hold all information for each class so it can be easily saved and read

            app.patch_handle = []; % handle for actually plotting the patch coordinates
            app.annotated_filepath_arr = [];
            app.annotator_name_arr = [];
            app.classname_arr = [];
            app.color_arr = [];
            app.z_plane_arr= [];
            app.save_array_bb = []; 
            app.conf_arr = [];

            app.annotated_filepath_arr_2 = [];
            app.annotator_name_arr_2 = [];
            app.classname_arr_2 = [];
            app.color_arr_2 = [];
            app.z_plane_arr_2 = [];
            app.save_array_bb_2 = []; 

            app.save_array = {};
            delete(app.current_patch)
            delete(app.patch_handle)
            cla(app.ImageAxes)
            update_save_array(app);
        end
        
        function reset_gTruth_annotations(app)
            
            app.patch_handle = []; % handle for actually plotting the patch coordinates
            
            app.annotated_filepath_arr_2 = [];
            app.annotator_name_arr_2 = [];
            app.classname_arr_2 = [];
            app.color_arr_2 = [];
            app.z_plane_arr_2 = [];
            app.save_array_bb_2 = []; 

            delete(app.current_patch);
            delete(app.patch_handle);
            update_save_array(app);

            cla(app.ImageAxes);
            update_image(app);
            update_image_plane(app);
        end

        function reset_DL_annotations(app)
            
            app.patch_handle = []; % handle for actually plotting the patch coordinates
            
            app.annotated_filepath_arr = [];
            app.annotator_name_arr = [];
            app.classname_arr = [];
            app.color_arr = [];
            app.z_plane_arr= [];
            app.save_array_bb = []; 
            app.conf_arr = [];

            delete(app.current_patch);
            delete(app.patch_handle);
            update_save_array(app);

            cla(app.ImageAxes);
            update_image(app);
            update_image_plane(app);
        end

        function update_save_array(app)
            app.save_array{1} = app.annotated_filepath_arr; % cell array or struct to store all the data we need
            app.save_array{2} = app.AnnotatorNameEditField.Value;
            app.save_array{3} = app.classname_arr';
            app.save_array{4} = app.color_arr';
            app.save_array{5} = app.z_plane_arr';
            app.save_array{6} = app.save_array_bb';
            app.save_array{7} = app.conf_arr';
            %app.save_array
        end
        
        function update_settings(app)
            %app.settings_data = readmatrix(, 'Delimiter',','); 
            app.settings_data = readstruct([app.executableFolder, '\Settings\settings.xml']);
            %need to update all the buttons
            app.SaveFileFormatDropDown.Value = app.settings_data.SaveFileFormat;
            app.ScrollSpeedEditField.Value = app.settings_data.ScrollSpeed;
            app.AnnotatorNameEditField.Value = app.settings_data.Annotator;
            app.MinObjectSizepixelsEditField.Value = app.settings_data.MinObjectSize;
        end
        
        function update_current_class(app)
            disp('need to update current class')
        end
        
        function draw_all(app)
            if app.load_annotations_sentinel == true
                if ~isempty(app.classname_arr)
                    unique_classes = unique(app.classname_arr);
                    for i = 1:length(unique_classes)
                        %ind = find(app.classname_arr==unique_classes(i));
                        %only draw indices of the bb from current stack_number
                        ind = find(app.classname_arr==unique_classes(i) & (app.z_plane_arr==app.stack_number));
                        if ~isempty(ind)
                            if ~isempty(app.conf_arr)
                                draw_rectangle(app, app.save_array_bb(:,ind), app.color_arr(:,ind(1))', app.conf_arr(ind) );
                                    %draw_processed_rectangle(app, app.save_array_bb(:,ind), app.color_arr(:,ind(1))', app.conf_arr(ind));
                            else
                                draw_rectangle(app, app.save_array_bb(:,ind), app.color_arr(:,ind(1))' );
                                %draw_processed_rectangle(app, app.save_array_bb(:,ind), app.color_arr(:,ind(1))');
                            end
                        end
                    end
                end
            end


            if ~isempty(app.classname_arr)
                unique_classes = unique(app.classname_arr);
                for i = 1:length(unique_classes)
                        %only draw indices of the bb from current stack_number
                        ind = find(app.classname_arr==unique_classes(i));
                        if ~isempty(ind)
                            if ~isempty(app.conf_arr)
                                draw_processed_rectangle(app, app.save_array_bb(:,ind), app.color_arr(:,ind(1))', app.conf_arr(ind));
                            else
                                draw_processed_rectangle(app, app.save_array_bb(:,ind), app.color_arr(:,ind(1))');
                            end
                        end
                end
            end
            
            stem = extractFileName(app, app.filename_stem);
            app.FilenameStemLabel.Text = stem;
        end
        
        function filename = extractFileName(app, filepath)
            % Find the last file separator (backslash) index
            lastsep = find(filepath == filesep, 1, 'last');
        
            % Extract the substring after the last file separator
            if ~isempty(lastsep)
                filename = filepath(lastsep+1:end);
            else
                % Handle case where there's no file separator
                filename = filepath;
            end 
        end 

        function update_classes_listbox(app)
            app.ClassnamesListBox.Items = app.class_list(:,5);
        end
        
        function load_classes(app)
            app.class_list = load([app.executableFolder, '\Settings\classes.mat']).classes;
            app.current_class = app.class_list{1,1};
            app.current_color = app.class_list{1,2};
            app.current_shape = app.class_list{1,3};
            update_classes_listbox(app);    
        end
        
        function update_annotation_list(app)
            if ~isempty(app.fds)
                %create file list and match the file to the image
                file_list_tmp = convertCharsToStrings(app.fds.Files);
                app.file_list= zeros([length(app.image_list),1]);
                for i =1:length(file_list_tmp)
                    tmp = find(erase(file_list_tmp(i),'.mat') == app.image_list);
                    app.file_list(tmp) = tmp;
                end
            else
                app.file_list= zeros([length(app.image_list),1]);
            end

            %if we want to randomize the images do that here
            if app.RandomizeImagefolderSwitch.Value
                app.randomizer = randperm(length(app.image_list));
            else
                app.randomizer = 1:1:length(app.image_list);
            end
            app.Status.Text = sprintf('Image (%d/%d)', app.image_number, length(app.image_list));
        end
        
        function load_and_display_image(app)
            app.image_handle = load_image(app,app.image_list(app.randomizer(app.image_number)));
            app.annotated_filepath_arr = convertStringsToChars(app.image_list(app.randomizer(app.image_number)));
            update_image(app);
            draw_all(app);
            app.ImageListDropDown.Enable = 'on';
            app.Status.Text = sprintf('Image (%d/%d)', app.image_number, length(app.image_list));
            app.time = 0;
            %stop(app.RandTimer);
            %start(app.RandTimer);
        end

        function update_grid_annotation(app)
             
            app.gridstep = round(app.WindowSizeEditField.Value*(1-(app.WindowOverlapEditField.Value/100)));
            app.num_blocksx = 1+ceil((app.image_size(2)-app.gridstep+app.WindowSizeEditField.Value)/app.gridstep);
            app.num_blocksy = 1+ceil((app.image_size(1)-app.gridstep+app.WindowSizeEditField.Value)/app.gridstep);
            app.UIAxes.XTick = 1:app.num_blocksx;
            app.UIAxes.YTick = 1:app.num_blocksy;
            app.UIAxes.XLim = [1,app.num_blocksx];
            app.UIAxes.YLim = [1,app.num_blocksy];
        end

         function update_grid_processed_annotation(app)
             
            app.gridstep = round(app.WindowSizeEditField.Value*(1-(app.WindowOverlapEditField.Value/100)));
            app.num_blocksx = 1+ceil((app.image_size(2)-app.gridstep+app.WindowSizeEditField.Value)/app.gridstep);
            app.num_blocksy = 1+ceil((app.image_size(1)-app.gridstep+app.WindowSizeEditField.Value)/app.gridstep);
            app.Processed_ImageAxes.XTick = 1:app.num_blocksx;
            app.Processed_ImageAxes.YTick = 1:app.num_blocksy;
            app.Processed_ImageAxes.XLim = [1,app.num_blocksx];
            app.Processed_ImageAxes.YLim = [1,app.num_blocksy];
        end

        function update_grid_FOV(app)
            plot(app.grid_idx(1)+0.5,app.grid_idx(2)+0.5,'r*', 'MarkerSize', 5, 'Parent', app.UIAxes);
            
            %update Image FOV based on grid selection
            app.ImageAxes.XLim = [1+(app.grid_idx(1)-1)*app.gridstep,1+(app.grid_idx(1)-1)*app.gridstep+app.WindowSizeEditField.Value];
            app.ImageAxes.YLim = [1+(app.grid_idx(2)-1)*app.gridstep,1+(app.grid_idx(2)-1)*app.gridstep+app.WindowSizeEditField.Value];
            app.zoom_limitsx = app.ImageAxes.XLim;
            app.zoom_limitsy = app.ImageAxes.YLim;
        end

        function update_grid_FOV_processed(app)
            plot(app.grid_idx(1)+0.5,app.grid_idx(2)+0.5,'r*', 'MarkerSize', 5, 'Parent', app.UIAxes);
            
            %update Image FOV based on grid selection
            app.Processed_ImageAxes.XLim = [1+(app.grid_idx(1)-1)*app.gridstep,1+(app.grid_idx(1)-1)*app.gridstep+app.WindowSizeEditField.Value];
            app.Processed_ImageAxes.YLim = [1+(app.grid_idx(2)-1)*app.gridstep,1+(app.grid_idx(2)-1)*app.gridstep+app.WindowSizeEditField.Value];
            app.zoom_limitsx = app.Processed_ImageAxes.XLim;
            app.zoom_limitsy = app.Processed_ImageAxes.YLim;
        end

        function output = process_image(app, img, processing_method)
            % for now just process with the fstack but generalize this code
            % so that we can run any filter function
            for i=1:size(img,4)
                img2{i} = img(:,:,:,i);
            end
            tmp_img = fstack(img2);
            max_int = app.minmaxintensitySpinner.Value/100;
            min_int = app.MinintensitySpinner.Value/100;
            output = imadjust(tmp_img, [min_int max_int]);
            app.img_fstack = tmp_img;
            %disp('Need to add process image functionality')
            %method_cmd = [processing_method, '(img)'];
            %output = eval(method_cmd);
        end

        function load_all_functions(app)
            % load all functions from the functions folder
            function_files = dir([app.executableFolder, '\Functions\*.m']);
            % parse through functions folder to make them into usable
            % commands
            for i =1:length(function_files)
                function_calls{i} = erase(function_files(i).name,'.m');
            end
            app.ProcessingFunctionsDropDown.Items = function_calls;
        end


        function load_focus_stack(app)
            % generates focus stack
            if length(size(app.processed_image_handle))>2
                h = waitbar(0,'Generating defocused stack ...');
                app.processed_image_handle = mat2gray(fstack(squeeze(num2cell(uint16(permute(app.processed_image_handle, [2 3 1])),[1 2])),...
                    'dilatesize', 9,'blendsize', 9));
                waitbar(1,h,'Finished.');
                close(h);
                fDS = imagesc(app.Processed_ImageAxes, app.processed_image_handle);
                x = app.minmaxintensitySpinner.Value/100;
                app.Processed_ImageAxes.CLim = [0 x];
                app.Processed_ImageAxes.Colormap = gray;
                app.Processed_ImageAxes.XLim = [0 fDS.XData(2)];
                app.Processed_ImageAxes.YLim = [0 fDS.YData(2)];
            end
        end
    end

    methods (Access = public)
        
        function image = load_image(app, filename)
            app.ImageAxes.Visible = 'on';
            info = imfinfo(filename); % get file info
            
            app.stack_size = length(info); % get stack size
            app.image_size = [info(1).Height, info(1).Width];
            %update slider
            if app.stack_size >1
                app.SliceSlider.Limits = [1 app.stack_size];
                app.SliceSlider.Visible = 'on';
            else
                app.SliceSlider.Visible = 'off';
            end
            app.SliceSlider.Value = 1;
            app.SliceSlider.MinorTicks = [];
            
            % Load images (check if it is a multipage tiff file)
            if (strcmp(convertCharsToStrings(info(1).Format), "tiff")) || (strcmp(convertCharsToStrings(info(1).Format), "tif"))
                tstack = Tiff(info(1).Filename);
                im = tstack.read();

                stackSize = [info(1).Height, info(1).Width, info(1).SamplesPerPixel, length(info)];
                stackSize = squeeze(stackSize);
                image = zeros(stackSize,'like',im);
                if length(info) > 1
                    image(:,:,:,1)  = tstack.read();
                else
                    
                    image(:,:,1)  = tstack.read();
                end
                
                
                for n = 2:length(info)
                    app.Status.Text = sprintf('Loaded Image (%d/%d)', n, length(info));
                    drawnow
                    tstack.nextDirectory()
                    if length(info) > 1
                        image(:,:,:,n)  = tstack.read();
                    else
                        image(:,:,n)  = tstack.read();
                    end
                end
                app.Status.Text = sprintf('');
            else
                %other file formats dont support multipage Z stacks so just
                %load iwht imread 
                app.Status.Text = sprintf('Loaded Image...');
                image = imread(info(1).Filename);
                app.Status.Text = sprintf('');
            end
            
            % I added this
            app.filename_stem = extractBefore(filename,".tif");
            
            app.filename_annotations = [convertStringsToChars(app.filename_stem), '.tif.mat'];
            app.filename_gTruth = [convertStringsToChars(app.filename_stem), '.mat'];
            

            if exist(app.filename_annotations, 'file')
                if strcmp(app.filename_annotations(end-7:end), '.tif.mat')
                    load_annotation(app, app.filename_annotations)
                else
                    load_gTruth(app, app.filename_annotations)
                end
            end

            update_grid_annotation(app);
            update_grid_processed_annotation(app);
            app.image_loaded = 1;


        end
        
        % I added this
        function load_gTruth(app, filename)
            app.save_array_2 = load(filename).gTruth;
            app.annotated_filepath_arr_2 = app.save_array_2.image_name;
            app.AnnotatorNameEditField.Value = 'YOLOv8';

            % If the mat gTruth file is formatted weirdly
            if length(fieldnames(app.save_array_2(1))) == 8
                app.num_defects_gTruth = app.save_array_2.myelin_count;
                charArray = repmat('Defect', app.num_defects_gTruth, 1);
                charArray = repmat(charArray, 23, 1);
                %app.classname_arr = convertCharsToStrings(string(app.save_array{3}))';
                app.classname_arr_2 = convertCharsToStrings(string(charArray))';
    
                %app.color_arr = app.save_array{4}';
                app.color_arr_2 = repmat([0 1 0], 23*app.num_defects_gTruth, 1)';
    
                %app.z_plane_arr = app.save_array{5}';
                all_z_plane_arr_2 = reshape(repmat(1:23, app.num_defects_gTruth, 1), [], 1);
                
                app.z_plane_arr_2 = int64(all_z_plane_arr_2)';
    
                %app.save_array_bb = app.save_array{6}';
                stackedArray = [];
                for i = 1:length(app.save_array_2.myelin)
                    stackedArray = [stackedArray; app.save_array_2.myelin{i}];
                end
                stackedArray = repmat(stackedArray, 23, 1);
                app.save_array_bb_2 = stackedArray';
    
                app.NumDefectsGTLabel.Text = string(app.num_defects_gTruth);
            else % Default Case
                app.num_defects_gTruth = app.save_array_2.N_myelin_def;
                charArray = repmat('Defect', app.num_defects_gTruth, 1);
                charArray = repmat(charArray, 23, 1);
                %app.classname_arr = convertCharsToStrings(string(app.save_array{3}))';
                app.classname_arr_2 = convertCharsToStrings(string(charArray))';
    
                %app.color_arr = app.save_array{4}';
                app.color_arr_2 = repmat([0 1 0], 23*app.num_defects_gTruth, 1)';
    
                %app.z_plane_arr = app.save_array{5}';
                all_z_plane_arr_2 = reshape(repmat(1:23, app.num_defects_gTruth, 1), [], 1);
                
                app.z_plane_arr_2 = int64(all_z_plane_arr_2)';
    
                %app.save_array_bb = app.save_array{6}';
                stackedArray = [];
                for i = 1:length(app.save_array_2.myelin)
                    stackedArray = [stackedArray; app.save_array_2.myelin{i}];
                end
                stackedArray = repmat(stackedArray, 23, 1);
                app.save_array_bb_2 = stackedArray';
    
                app.NumDefectsGTLabel.Text = string(app.num_defects_gTruth);
            end
            

        end


        function load_annotation(app, filename)
            app.save_array = load(filename).annotations;
            app.annotated_filepath_arr = app.save_array{1};
            app.AnnotatorNameEditField.Value = app.save_array{2};
            app.classname_arr = convertCharsToStrings(string(app.save_array{3}))';
            app.color_arr = app.save_array{4}';
            app.z_plane_arr = app.save_array{5}';
            app.num_defects_annotations = length(app.z_plane_arr);
            app.save_array_bb = app.save_array{6}';
            if length(app.save_array) >= 7
                app.conf_arr = app.save_array{7}';
            end
            app.NumDefectsAnnLabel.Text = string(app.num_defects_annotations);
        end
        
        function reset_timer(app)
            if strcmp(app.RandTimer.Running, 'off')
                %start(app.RandTimer);
            else
                app.time = 0;
                %stop(app.RandTimer);
                %start(app.RandTimer);
            end
        end
        
        
        function inside_ROI = check_inside_ROI(~,point,patch)
            inside_ROI = 0;
            if ~isempty(patch)
                % return index of any patches that we are clicked inside of
                R = find(point(1) > patch(1,:));
                L = find(point(1) < patch(1,:)+patch(3,:));
                U = find(point(2) < patch(2,:)+patch(4,:));
                D = find(point(2) > patch(2,:));
                inside_ROI = intersect(R,L);
                inside_ROI = intersect(inside_ROI, U);
                inside_ROI = intersect(inside_ROI, D);
            end
        end

        function tmp_patch = draw_rectangle(app, coordinates,color, conf)
            %delete coordinates outisde confidence interval
            if nargin == 4
                ind_conf = conf > app.ConfidenceField.Value;
                coordinates = coordinates(:, ind_conf);
                conf = conf(:, ind_conf);
            end
            
            % put into proper format for the patch to display the bb
            tmp_x = [coordinates(1,:); coordinates(1,:) + coordinates(3,:); coordinates(1,:)+ coordinates(3,:); coordinates(1,:)];
            tmp_y = [coordinates(2,:) + coordinates(4,:); coordinates(2,:)+ coordinates(4,:); coordinates(2,:); coordinates(2,:)];
            
            tmp_patch = patch(tmp_x,tmp_y, 'red', 'FaceColor', 'none', 'EdgeColor', color, 'Parent', app.ImageAxes, 'EdgeAlpha', app.TransparencyField.Value);

           if nargin == 4
                % If the conf parameter is included (for DL annotations), display a label for the annotation box
                label = round(conf,2);

                for i=1:length(conf)
                    cur_label = num2str(label(i));
                    if coordinates(2,i) < 20 % do i need to do coordinates(2,:)??
                        % if the bbox is close to the top of the image (the y coordinate), print
                        % label below it
                        labelX = min(tmp_x(:,i)) + (max(tmp_x(:,i)) - min(tmp_x(:,i))) / 2;
                        labelY = max(tmp_y(:,i)) + 10;
                        text(labelX, labelY, cur_label, 'HorizontalAlignment', 'center', 'Color', [1 0 0 app.TextTransparencyField.Value], 'Parent', app.ImageAxes);
                    else
                        % otherwise, print label above it
                        labelX = min(tmp_x(:,i)) + (max(tmp_x(:,i)) - min(tmp_x(:,i))) / 2;
                        labelY = min(tmp_y(:,i)) - 10;
                        text(labelX, labelY, cur_label, 'HorizontalAlignment', 'center', 'Color', [1 0 0 app.TextTransparencyField.Value], 'Parent', app.ImageAxes);
                    end
                end
           end
        end

        function tmp_patch = draw_processed_rectangle(app, coordinates,color, conf)
            %delete coordinates outisde confidence interval
            if nargin == 4
                ind_conf = conf > app.ConfidenceField.Value;
                coordinates = coordinates(:, ind_conf);
                conf = conf(:, ind_conf);
            end

            % put into proper format for the patch to display the bb
            tmp_x = [coordinates(1,:); coordinates(1,:) + coordinates(3,:); coordinates(1,:)+ coordinates(3,:); coordinates(1,:)];
            tmp_y = [coordinates(2,:) + coordinates(4,:); coordinates(2,:)+ coordinates(4,:); coordinates(2,:); coordinates(2,:)];

            tmp_patch = patch(tmp_x,tmp_y, 'red', 'FaceColor', 'none', 'EdgeColor', color, 'Parent', app.Processed_ImageAxes, 'EdgeAlpha', app.TransparencyField.Value);
            if nargin == 4
                % If the conf parameter is included (for DL annotations), display a label for the annotation box
                label = round(conf,2);

                for i=1:length(conf)
                    cur_label = num2str(label(i));
                    if coordinates(2,i) < 20 % do i need to do coordinates(2,:)??
                        % if the bbox is close to the top of the image (the y coordinate), print
                        % label below it
                        labelX = min(tmp_x(:,i)) + (max(tmp_x(:,i)) - min(tmp_x(:,i))) / 2;
                        labelY = max(tmp_y(:,i)) + 10;
                        text(labelX, labelY, cur_label, 'HorizontalAlignment', 'center', 'Color', [1 0 0 app.TextTransparencyField.Value], 'Parent', app.Processed_ImageAxes);
                    else
                        % otherwise, print label above it
                        labelX = min(tmp_x(:,i)) + (max(tmp_x(:,i)) - min(tmp_x(:,i))) / 2;
                        labelY = min(tmp_y(:,i)) - 10;
                        text(labelX, labelY, cur_label, 'HorizontalAlignment', 'center', 'Color', [1 0 0 app.TextTransparencyField.Value], 'Parent', app.Processed_ImageAxes);
                    end
                end
           end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % get main file location for the app
            if isdeployed
    			% User is running an executable in standalone mode.
    			[~ , result] = system('set PATH');
    			app.executableFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    		else
    			% User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
                tmp_path = mfilename('fullpath');
                [app.executableFolder , ~ , ~] = fileparts(tmp_path);
            end
            
            %%% need to create the folders (Settings, DL_Model etc.) if the
            %%% app has never been open before
            if ~exist([app.executableFolder, '\Settings'], 'dir')
                mkdir([app.executableFolder, '\Settings'])
            end
            if ~exist([app.executableFolder, '\DL_models'], 'dir')
                mkdir([app.executableFolder, '\DL_models'])
            end
            if ~exist([app.executableFolder, '\Functions'], 'dir')
                mkdir([app.executableFolder, '\Functions'])
            end
            if ~exist([app.executableFolder, '\Settings\settings.xml'], 'file')
                disp('Doesnt Exist')
                app.settings_data.Annotator = 'Default';
                app.settings_data.ScrollSpeed = 3;
                app.settings_data.SaveFileFormat = '.mat';
                app.settings_data.MinObjectSize = 20;
                writestruct(app.settings_data,[app.executableFolder, '\Settings\settings.xml']);
            end
            if ~exist([app.executableFolder, '\Settings\classes.mat'], 'file')
                classes = app.class_list;
                save([app.executableFolder, '\Settings\classes.mat'], 'classes');
            end
            
            %load Classnames from GUI file 
            load_classes(app);
            update_settings(app); % need to clean this file up
            %load_all_functions(app);
 
            % update shortcut_list
            app.shortcut_list = erase(app.shortcut_list, app.class_list(:,4));
            app.ShortcutEditField.Value = app.shortcut_list(1);
           
            % Update Icons for the Buttons
            %app.PauseBtn.Icon = 'Pause_button.png';
            %app.PlayBtn.Icon = 'Play_button.png';
            %app.logo = imread('LOGO.png');

            % Update Icons for the Buttons
%             app.PauseBtn.Icon = [app.executableFolder, '\Settings\Pause_button.png'];
%             app.PlayBtn.Icon = [app.executableFolder, '\Settings\Play_button.png'];
%             app.logo = imread([app.executableFolder, '\Settings\LOGO.png']);

            %initialize all values for save array
            %app.current_class = 

            app.ImageAxes.Toolbar.Visible = 'on';   % or 'off' to disable

            % Create timer object
            app.RandTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.RandTimerFcn);      % Specify callback function
            % set the focus for the GUI
            figure(app.UIFigure)
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            %filename = uigetfile(pwd);
            [filename, pathname, ~] = uigetfile( ...
                {'*.*',  'All Files (*.*)'}, ...
                'Pick an image of the following format: .png .jpeg .tiff .bmp');
            reset_properties(app);
            if filename
                app.image_handle = load_image(app,[pathname, filename]);
                app.annotated_filepath_arr = [pathname, filename];
                
                %set axis limits so zoom functionality doesnt reset
                app.zoom_limitsx = [0,size(app.image_handle,2)];
                app.zoom_limitsy = [0,size(app.image_handle,1)];

%                 % initialises proccessed_image_handle
%                 info = imfinfo([pathname, filename]);
%                 for u = 1:length(info)
%                     if u == 1
%                         temp = imread([pathname, filename],1);
%                         app.processed_image_handle = uint16(zeros([length(info) size(temp)]));
%                         app.processed_image_handle(u,:,:,:) = temp;
%                     else
%                         app.processed_image_handle(u,:,:,:) = imread([pathname, filename],u);
%                     end
%                 end
            end
            app.ImageListDropDown.Enable = 'off';
            app.PreviousButton.Enable = 'off';
            app.NextButton.Enable = 'off';
            update_image(app);
            %load_focus_stack(app)
            draw_all(app);
            app.time = 0;
            %stop(app.RandTimer);
            %start(app.RandTimer);
        end

        % Button pushed function: ImportFolderButton
        function ImportFolderButtonPushed(app, event)
            % create data store for the images in the main folder for
            % annotation 
            %try
                selpath = uigetdir(app.executableFolder); % select folder path
                reset_properties(app);
                app.image_folder = selpath;
                if selpath
                    app.folder_loaded = 1; % check if we loaded in multiple images
                    app.image_number = 1;
                    app.imds = imageDatastore(app.image_folder, 'IncludeSubfolders',app.IncludeSubfoldersCheckBox.Value);
                    try
                        app.fds = fileDatastore(app.image_folder,"ReadFcn",@load,"FileExtensions",".mat", 'IncludeSubfolders',app.IncludeSubfoldersCheckBox.Value);
                    catch
                        app.fds = [];
                    end
                    app.image_list = convertCharsToStrings(app.imds.Files);
                    % get image datastore and file datastore and do
                    % randomizing on it
                    update_annotation_list(app);
                    
                    app.PreviousButton.Enable = 'on';
                    app.NextButton.Enable = 'on';
                    app.image_handle = load_image(app,app.image_list(app.randomizer(app.image_number)));

                    % set axis limits to make sure zoom functioanlity
                    % doesnt reset it
                    app.zoom_limitsx = [0,size(app.image_handle,2)];
                    app.zoom_limitsy = [0,size(app.image_handle,1)];


                    app.annotated_filepath_arr = convertStringsToChars(app.image_list(app.randomizer(app.image_number)));
                    
                    update_image(app);
                    draw_all(app);
                    app.ImageListDropDown.Enable = 'on';
                    app.time = 0;
                    %stop(app.RandTimer);
                    %start(app.RandTimer);
                else
                    disp('No path selected')
                end
            %catch ME
            %    errorMessage = sprintf('Error in function %s() at line %d.\n\nError Message: Error Importing image folder', ...
            %    ME.stack(1).name, ME.stack(1).line);
            %    uiwait(warndlg(errorMessage));
            %end
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
%             disp(app.stack_number);
%             disp(app.SliceSlider.Value);
            if app.image_loaded
                app.stack_number = round(app.SliceSlider.Value);
                cla(app.ImageAxes)
                update_image(app);
                update_image_plane(app)
                draw_all(app);
            end

        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
%             app.stack_number = app.SliceSlider.Value;
%             update_image(app);
%                 disp('need to add functioanlity ')
        end

        % Value changed function: minmaxintensitySpinner
        function minmaxintensitySpinnerValueChanged(app, event)
            update_image(app);
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            if app.image_loaded
                verticalScrollCount = event.VerticalScrollCount;

                app.stack_number = app.stack_number + verticalScrollCount*app.ScrollSpeedEditField.Value;

                if app.stack_number <1
                    app.stack_number = 1;%app.stack_size;
                elseif app.stack_number > app.stack_size
                    app.stack_number = app.stack_size;
                end
                cla(app.ImageAxes)
                update_image(app);
                update_image_plane(app)
                draw_all(app);
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            % check wheter one of the shortuc keys was pressed
            key_ind = find(cell2mat(app.class_list(:,4)) == key);
            key_select = app.class_list(key_ind,4);
            disp(key)
            switch (key)
                case 'shift'
                    if strcmp(app.HideAllLinesSwitch.Value, 'Off')
                        app.HideAllLinesSwitch.Value = 'On';
                    else
                        app.HideAllLinesSwitch.Value = 'Off';
                    end
                    HideAllLinesSwitchValueChanged(app, app.HideAllLinesSwitch.Value)

                case 'alt'
                    app.MultiplePlanesButton.Value = 1;

                case 'control'
                    % do we want to toggle the zoom mode or only if you
                    % press down to zoom (maybe want to be able to scroll
                    % zoom
                    app.ZoomButton.Value = ~app.ZoomButton.Value; % toggle zoom
                    app.zoom_mode = app.ZoomButton.Value;
%                     if app.ZoomButton.Value
%                         zoom(app.ImageAxes,'on');
%                     else
%                         zoom(app.ImageAxes,'off');
%                     end
                case 'space'
                    app.ImageAxes.XLim = [0,size(app.image_handle,2)];
                    app.ImageAxes.YLim = [0,size(app.image_handle,1)];
                    app.zoom_limitsx = [0,size(app.image_handle,2)];
                    app.zoom_limitsy = [0,size(app.image_handle,1)];

                case key_select
                    app.ClassnamesListBox.Value = key_ind;
                    app.CurrentClassLbl.Text = sprintf('Name: %s | Shortcut: %s', app.class_list{key_ind,1},app.class_list{key_ind,4});
                    app.CurrentClassLbl.FontColor = app.class_list{key_ind, 2};
                    app.current_class = app.class_list{key_ind,1};
                    app.current_color = app.class_list{key_ind,2};
                    app.current_shape = app.class_list{key_ind,3};
                    app.current_shortcut = app.class_list{key_ind,4};
                
                case 'f'
                    if app.image_loaded
                        if app.stack_number < 23
                            app.stack_number = app.stack_number + 1;
                            cla(app.ImageAxes)
                            update_image(app);
                            update_image_plane(app)
                            draw_all(app);
                        else
                            app.stack_number = 23;
                        end
                    end

                % case 'd'
                %     if app.image_loaded
                %         if app.stack_number > 1
                %             app.stack_number = app.stack_number - 1;
                %             cla(app.ImageAxes)
                %             update_image(app);
                %             update_image_plane(app)
                %             draw_all(app);
                %         else
                %             app.stack_number = 1;
                %         end
                %     end

                case 'a'
                    % decrease the index of the next cropped defect to show
                    if (app.defect_idx==1)
                        app.Label.Text = 'No more defects';
                    else
                        app.defect_idx = app.defect_idx - 1;
                        app.Label.Text = sprintf('%d of %d', app.defect_idx, length(app.save_array_bb));
                    end
                    z = app.z_plane_arr(app.defect_idx);
                    app.defect_z = z;
                    update_defect_viewer(app, z);
                case 'd'
                     % increase the index of the next cropped defect to show
                    if (app.defect_idx==length(app.save_array_bb))
                        app.Label.Text = 'No more defects';
                    else
                        app.defect_idx = app.defect_idx + 1;
                        app.Label.Text = sprintf('%d of %d', app.defect_idx, length(app.save_array_bb));
                    end
                    z = app.z_plane_arr(app.defect_idx);
                    app.defect_z = z;
                    update_defect_viewer(app, z);
                    
                case 'w'
                    %move to the plane upward for current defect
                    if (app.z_plane_arr(app.defect_idx) == size(app.image_handle,4))
                        app.Label.Text = 'Maximum plane';
                        app.defect_z = size(app.image_handle,4);
                    else
                        app.Label.Text = sprintf('%d of %d', app.defect_idx, length(app.save_array_bb));
                        app.defect_z = app.defect_z + 1;
                    end
                    update_defect_viewer(app, app.defect_z);

                case 's'
                    %move to the plane down for current defect
                    if (app.z_plane_arr(app.defect_idx) == 1)
                        app.Label.Text = 'Minimum plane';
                        app.defect_z = 1;
                    else
                        app.Label.Text = sprintf('%d of %d', app.defect_idx, length(app.save_array_bb));
                        app.defect_z = app.defect_z - 1;
                    end
                    update_defect_viewer(app, app.defect_z);

                case 'r'
                    % remove defect
                    app.save_array_bb(:,app.defect_idx) = [];
                    app.color_arr(:,app.defect_idx) = []; %append our current color for our bounding box
                    app.z_plane_arr(app.defect_idx) = [];
                    app.classname_arr(app.defect_idx) = [];
                    app.conf_arr(app.defect_idx) = [];
                    cla(app.ImageAxes);
                    update_image(app);
                    draw_all(app);
                    % show current defect
                    if (app.defect_idx==length(app.save_array_bb))
                        app.Label.Text = 'No more defects';
                    else
                        app.defect_idx = app.defect_idx;
                        app.Label.Text = sprintf('%d of %d', app.defect_idx, length(app.save_array_bb));
                    end
                    z = app.z_plane_arr(app.defect_idx);
                    app.defect_z = z;
                    update_defect_viewer(app, z);

                case 'b'
                    app.classname_arr(app.defect_idx) = "Background ";
                    app.color_arr(:,app.defect_idx) = [1; 1; 0];
                    update_defect_viewer(app, app.defect_z);
                    

                % arrows control the grid annotation 
                case 'leftarrow'
                    if app.grid_idx(1) > 1
                        app.grid_idx(1) = app.grid_idx(1) -1;
                        update_grid_FOV(app);
                        update_grid_FOV_processed(app);
                    end
                    
                case 'rightarrow'
                    if app.grid_idx(1) < app.num_blocksx -1
                        app.grid_idx(1) = app.grid_idx(1) +1;
                        update_grid_FOV(app);
                        update_grid_FOV_processed(app);
                    end
                    
                case 'downarrow'
                    if app.grid_idx(2) < app.num_blocksy -1
                        app.grid_idx(2) = app.grid_idx(2) +1;
                        update_grid_FOV(app);
                        update_grid_FOV_processed(app);
                    end
                case 'uparrow'
                    if app.grid_idx(2) > 1
                        app.grid_idx(2) = app.grid_idx(2) -1;
                        update_grid_FOV(app);
                        update_grid_FOV_processed(app);
                    end
            end
            
        end

        % Button pushed function: FinishSaveButton
        function FinishSaveButtonPushed(app, event)
            try
                stop(app.RandTimer);
            catch
                disp('Timer broken still')
            end

            % make sure image is loaded if we wanna save
            if app.image_loaded
                update_save_array(app);
                annotations = app.save_array;
                answer = 'Yes';
                if isempty(annotations{3})
                    answer = questdlg('NO OBJECTS LABELED! Do you want to save?','No objects labeled','Yes','No','No');
                end
                if strcmp(answer,'Yes')
                    annotations{1,3} = char(annotations{1,3});
                    switch app.SaveFileFormatDropDown.Value
                        case '.mat'
                            save([app.annotated_filepath_arr, '.mat'], 'annotations')
                            disp('Save as .Mat File')
                        case 'Text'
                            writecell(annotations, [app.annotated_filepath_arr, '.txt'])
                            disp('Save as Table File')
                        case 'Table'
                            writecell(annotations, [app.annotated_filepath_arr, '.xls'])
                            disp('Save as Text File')
                        case 'JSON'
                            disp('need to use jsonencode() function in matlab')
                            disp('Save as JSON File')
                    end
                    reset_properties(app);
                    if app.folder_loaded
                        app.image_number = app.image_number + 1;
                        if app.image_number > length(app.image_list)
                            app.image_number = 1;
                        end
                        disp('%%%%%%%%% save array bb %%%%%%%%%%')
                        disp(size(app.save_array_bb))
                        load_and_display_image(app);
                    end
                end
            end
            
        end

        % Callback function
        function PauseBtnPushed(app, event)
            %stop(app.RandTimer);
        end

        % Callback function
        function PlayBtnPushed(app, event)
            %start(app.RandTimer);
        end

        % Callback function
        function InfoButtonPushed(app, event)
            msgbox('Press Shift to toggle hiding lines and Ctrl to toggle hiding rectangles.', 'icon', 'help')
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            reset_properties(app);
        end

        % Callback function
        function ImageAxesButtonDown(app, event)
            %app.X_rect = round(app.ImageAxes.CurrentPoint(1,1));
            %app.Y_rect = round(app.ImageAxes.CurrentPoint(1,2));
            app.button_clicked = 1;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            % Get the current axes limits (after zooming)
            xLimits = app.ImageAxes.XLim;
            yLimits = app.ImageAxes.YLim;

            if (app.ImageAxes.CurrentPoint(1,1) > max(0, xLimits(1))) && (app.ImageAxes.CurrentPoint(1,1) < min(app.image_size(2), xLimits(2))) ...
                && (app.ImageAxes.CurrentPoint(1,2) > max(0, yLimits(1))) && (app.ImageAxes.CurrentPoint(1,2) < min(app.image_size(1), yLimits(2)))
    
            % if (app.ImageAxes.CurrentPoint(1,1) > 0) && (app.ImageAxes.CurrentPoint(1,1) < app.image_size(2))...
            %         && (app.ImageAxes.CurrentPoint(1,2) > 0) && (app.ImageAxes.CurrentPoint(1,2) < app.image_size(1))

                % make a check to see if the grid annotation is clicked so
                % we can change the parameters for annotation drawing
                
                app.X_rect = round(app.ImageAxes.CurrentPoint(1,1));
                app.Y_rect = round(app.ImageAxes.CurrentPoint(1,2));

                % handle the situation when someone just clicks on a
                % different plane 
                Width = round(app.ImageAxes.CurrentPoint(1,1)-app.X_rect);
                Height = round(app.ImageAxes.CurrentPoint(1,2)-app.Y_rect);
                coordinates = [app.X_rect, app.Y_rect, Width, Height];
                %%%% handle all types of dragging of the rectangle
                %%%% to make sure it ends up the same shape drawn
                %%%% (not negative)
                if Width < 0 && Height < 0
                    coordinates = [app.X_rect+Width, app.Y_rect+Height, abs(Width), abs(Height)];
                elseif Height < 0 && Width > 0
                    coordinates = [app.X_rect, app.Y_rect+Height, abs(Width), abs(Height)];
                elseif Height > 0 && Width < 0
                    coordinates = [app.X_rect+Width, app.Y_rect, abs(Width), abs(Height)];
                end
                app.current_bb = coordinates';
                app.current_patch = draw_rectangle(app, coordinates', app.current_color);

                if strcmp(event.Source.SelectionType,'normal')
                    app.button_clicked = 1;
                    app.starting_z = app.stack_number; % used to annotate multiple planes in an image
                elseif strcmp(event.Source.SelectionType,'alt')
                    %if we right click lets potentially delete bounding box
                    app.ROI_selected_index = check_inside_ROI(app,[app.X_rect,app.Y_rect],app.save_array_bb);
                    if app.ROI_selected_index
                        app.save_array_bb(:,app.ROI_selected_index) = [];
                        app.color_arr(:,app.ROI_selected_index) = []; %append our current color for our bounding box
                        app.z_plane_arr(app.ROI_selected_index) = [];
                        app.classname_arr(app.ROI_selected_index) = [];
                        app.conf_arr(app.ROI_selected_index) = [];
                        cla(app.ImageAxes);
                        update_image(app);
                        update_processed_image(app);
                        draw_all(app);

                    end
                end
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
             if app.button_clicked
                delete(app.current_patch)
                Width = round(app.ImageAxes.CurrentPoint(1,1)-app.X_rect);
                Height = round(app.ImageAxes.CurrentPoint(1,2)-app.Y_rect);
                coordinates = [app.X_rect, app.Y_rect, Width, Height];
                %%%% handle all types of dragging of the rectangle
                %%%% to make sure it ends up the same shape drawn
                %%%% (not negative)
                if Width < 0 && Height < 0
                    coordinates = [app.X_rect+Width, app.Y_rect+Height, abs(Width), abs(Height)];
                elseif Height < 0 && Width > 0
                    coordinates = [app.X_rect, app.Y_rect+Height, abs(Width), abs(Height)];
                elseif Height > 0 && Width < 0
                    coordinates = [app.X_rect+Width, app.Y_rect, abs(Width), abs(Height)];
                end
                app.current_bb = coordinates';
                app.current_patch = draw_rectangle(app, coordinates', app.current_color);
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            % make sure I am actually clicking in the plot
            if app.button_clicked
                if (app.ImageAxes.CurrentPoint(1,1) > 0) && (app.ImageAxes.CurrentPoint(1,1) < app.image_size(2))...
                        && (app.ImageAxes.CurrentPoint(1,2) > 0) && (app.ImageAxes.CurrentPoint(1,2) < app.image_size(1))
                    if app.ZoomButton.Value
                        delete(app.patch_handle)
                        app.zoom_mode = 0;
                        app.ZoomButton.Value = 0;
                        if (app.X_rect < app.ImageAxes.CurrentPoint(1,1)) && (app.Y_rect < app.ImageAxes.CurrentPoint(1,2))
                            app.ImageAxes.XLim = [app.X_rect app.ImageAxes.CurrentPoint(1,1)];
                            app.ImageAxes.YLim = [app.Y_rect app.ImageAxes.CurrentPoint(1,2)];
                        elseif (app.X_rect > app.ImageAxes.CurrentPoint(1,1)) && (app.Y_rect > app.ImageAxes.CurrentPoint(1,2))
                            app.ImageAxes.XLim = [app.ImageAxes.CurrentPoint(1,1) app.X_rect];
                            app.ImageAxes.YLim = [app.ImageAxes.CurrentPoint(1,2) app.Y_rect];
                        elseif (app.X_rect > app.ImageAxes.CurrentPoint(1,1)) && (app.Y_rect < app.ImageAxes.CurrentPoint(1,2))
                            app.ImageAxes.XLim = [app.ImageAxes.CurrentPoint(1,1) app.X_rect];
                            app.ImageAxes.YLim = [app.Y_rect app.ImageAxes.CurrentPoint(1,2)];
                        elseif (app.X_rect < app.ImageAxes.CurrentPoint(1,1)) && (app.Y_rect > app.ImageAxes.CurrentPoint(1,2))
                            app.ImageAxes.XLim = [app.X_rect app.ImageAxes.CurrentPoint(1,1)];
                            app.ImageAxes.YLim = [app.ImageAxes.CurrentPoint(1,2) app.Y_rect];
                        end
                        app.zoom_limitsx = app.ImageAxes.XLim;
                        app.zoom_limitsy = app.ImageAxes.YLim;

                    elseif (app.current_bb(3) > app.MinObjectSizepixelsEditField.Value) && (app.current_bb(4) > app.MinObjectSizepixelsEditField.Value)
                        % make sure that our object size is within our
                        % minimum object size setting
                        disp('Somehow I got here even if I just clicked')
                            for k = min(app.starting_z,app.stack_number):max(app.starting_z,app.stack_number)
                                app.color_arr = [app.color_arr,app.current_color']; %append our current color for our bounding box
                                app.conf_arr = [app.conf_arr, 1];
                                app.z_plane_arr = [app.z_plane_arr, k];
                                app.classname_arr = [app.classname_arr, convertCharsToStrings(app.current_class)]; %make sure they are all strings
                                app.save_array_bb = [app.save_array_bb, app.current_bb];
                                app.patch_handle = draw_rectangle(app,app.save_array_bb, [1 0 0]);
                            end
                                update_save_array(app);
                                draw_all(app);
                    end
                end
                delete(app.current_patch)
                app.button_clicked = 0;
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.image_number = app.image_number + 1;
            if app.image_number > length(app.image_list)
                app.image_number = 1;
            end
            reset_properties(app);
            load_and_display_image(app);
            if app.load_gTruth_sentinel == true
%                 disp("GOING HERE");
                app.GroundTruthCheckBox.Value = false;
                GroundTruthCheckBoxValueChanged(app, event);
                app.GroundTruthCheckBox.Value = true;
                GroundTruthCheckBoxValueChanged(app, event);
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.image_number = app.image_number - 1;
            if app.image_number < 1
                app.image_number = length(app.image_list);
            end
            reset_properties(app);
            load_and_display_image(app);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web('https://github.com/alexgray103/BiRScope');
        end

        % Value changed function: HideAllRectanglesSwitch
        function HideAllRectanglesSwitchValueChanged(app, event)
            value = app.HideAllRectanglesSwitch.Value;
            %if we dont want to display dont display
            %maybe we wanna be able to only display a certain set of
            %classes
            if value
                draw_all(app);
            else
                delete(app.patch_handle)
            end
        end

        % Button pushed function: AddClassButton
        function AddClassButtonPushed(app, event)
            if ~isempty(app.ClassnameEditField.Value)
                %app.ClassnamesListBox.Items{end+1} = sprintf('%s - Shortcut: %s', app.ClassnameEditField.Value, 1);
                %check if the shortcut is already in our list and if it is
                %then throw an error

                shortcut_flag = ismember(app.ShortcutEditField.Value, app.class_list(:,4));

                if shortcut_flag
                    errordlg('Shortcut already taken')
                else
                    app.class_list = [app.class_list; {app.ClassnameEditField.Value, app.ColorSelectorBtn.BackgroundColor, app.ClassshapeDropDown.Value, app.ShortcutEditField.Value, sprintf('Name: %s | Shortcut "%s"', app.ClassnameEditField.Value,app.ShortcutEditField.Value)}]; % list of all classes to choose from (and index from)
                    app.shortcut_list = erase(app.shortcut_list, app.class_list(:,4));
                    app.ShortcutEditField.Value = app.shortcut_list(1);
                    update_classes_listbox(app);

                    %save the classes
                    classes = app.class_list;
                    save([app.executableFolder, '\Settings\classes.mat'], 'classes');
                end
            else
                errorMessage = 'No classname provided. Enter classname to Create new class';
                uiwait(warndlg(errorMessage), 5);
            end
        end

        % Button pushed function: ColorSelectorBtn
        function ColorSelectorBtnPushed(app, event)
            color_tmp = uisetcolor();
            if ~isempty(color_tmp)
                app.color_new = color_tmp;
            end
            try
                app.ColorSelectorBtn.BackgroundColor = app.color_new;
            catch
                
                errordlg('Something Went Wrong Try to select Color Again')
            end
        end

        % Button pushed function: EditCurrentClassButton
        function EditCurrentClassButtonPushed(app, event)
            value = app.ClassnamesListBox.Value;
            if ~isempty(app.ClassnameEditField.Value)
                
                answer = questdlg(sprintf('Are you sure you want to edit "%s" class? The new class will have the following characteristics: \nName: %s\nColor: %s\nShape: %s\nShortcut: %s', ...
                    app.class_list{value,1},app.class_list{value,1},strjoin(string(app.class_list{value,2})),app.class_list{value,3},app.class_list{value,4}),'Edit Class: ','Yes','No','No');
                if strcmp(answer,'Yes')
                    app.class_list{value,1} = app.ClassnameEditField.Value;
                    app.class_list{value,2} = app.ColorSelectorBtn.BackgroundColor;
                    app.class_list{value,3} = app.ClassshapeDropDown.Value;
                    app.class_list{value,4} = app.ShortcutEditField.Value;
                    app.class_list{value,5} = sprintf('Name: %s | Shortcut "%s"', app.ClassnameEditField.Value,app.ShortcutEditField.Value); % list of all classes to choose from (and index from)
                    update_classes_listbox(app);

                    %save the classes
                    classes = app.class_list;
                    save([app.executableFolder, '\Settings\classes.mat'], 'classes');
                end
            else
                errorMessage = sprintf('No Classname provided. Enter Classname to edit the "%s" class', app.class_list{value,1});
                uiwait(warndlg(errorMessage), 5);
            end
        end

        % Value changed function: ClassnamesListBox
        function ClassnamesListBoxValueChanged(app, event)
            value = app.ClassnamesListBox.Value;
            app.CurrentClassLbl.Text = sprintf('Name: %s | Shortcut: %s', app.class_list{value,1},app.class_list{value,4});
            app.CurrentClassLbl.FontColor = app.class_list{value, 2};
            app.current_class = app.class_list{value,1};
            app.current_color = app.class_list{value,2};
            app.current_shape = app.class_list{value,3};
            app.current_shortcut = app.class_list{value,4};
        end

        % Value changed function: ShortcutEditField
        function ShortcutEditFieldValueChanged(app, event)
            value = app.ShortcutEditField.Value;
            if length(value) > 1
                app.ShortcutEditField.Value = value(1);
            end

            % if we already have that shortcut make sure to throw an error
            % and put it to something that can be used
            shortcut_flag = ismember(app.ShortcutEditField.Value, app.class_list(:,4));
            if shortcut_flag
                app.ShortcutEditField.Value = app.shortcut_list(1);
                errorMessage = sprintf('Shortcut Already in use. Shortcut value set to "%s"', app.shortcut_list(1));
                g = warndlg(errorMessage);
                uiwait(g,4);
                delete(g);
            end
        end

        % Callback function
        function SaveClassnamesButtonPushed(app, event)
            disp('need to save classnames into a mat file or text file so taht it can be loaded next time we open the GUI')
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.settings_data.Annotator =  app.AnnotatorNameEditField.Value;
            app.settings_data.ScrollSpeed = app.ScrollSpeedEditField.Value;
            app.settings_data.SaveFileFormat = app.SaveFileFormatDropDown.Value;
            app.settings_data.MinObjectSize = app.MinObjectSizepixelsEditField.Value;
            writestruct(app.settings_data,[app.executableFolder, '\Settings\settings.xml']);
        end

        % Callback function
        function ScaleButtonPushed(app, event)
            app.hImage.CDataMapping = 'scaled';
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            value = app.ZoomButton.Value;
            app.zoom_mode = value;
%             if ~value
%                 zoom(app.ImageAxes,'on');
%             else
%                 zoom(app.ImageAxes,'off');
%             end
        end

        % Value changed function: ImageListDropDown
        function ImageListDropDownValueChanged(app, event)
            value = app.ImageListDropDown.Value;
            app.image_list = convertCharsToStrings(app.imds.Files);
            switch value
                case 'All'
                    app.image_number = 1;
                    app.image_list = convertCharsToStrings(app.imds.Files);
                    update_annotation_list(app);
                    load_and_display_image(app);
                case 'Un-Annotated'
                    app.image_number = 1;
                    app.image_list = convertCharsToStrings(app.imds.Files);
                    update_annotation_list(app);
                    app.image_list = convertCharsToStrings(app.imds.Files((find(~app.file_list))));
                    update_annotation_list(app);
                    load_and_display_image(app);
                case 'Annotated'
                    app.image_number = 1;
                    app.image_list = convertCharsToStrings(app.imds.Files);
                    update_annotation_list(app);
                    app.image_list = convertCharsToStrings(app.imds.Files((find(app.file_list))));
                    update_annotation_list(app);
                    load_and_display_image(app);
            end
        end

        % Button pushed function: DeleteCurrentClassButton
        function DeleteCurrentClassButtonPushed(app, event)
            value = app.ClassnamesListBox.Value;
            answer = questdlg(sprintf('Are you sure you want to delete the "%s" class',app.class_list{value,1}),'Delete selected class','Yes','No','No');
            if strcmp(answer,'Yes')
                app.class_list(value,:) = [];
                classes = app.class_list;
                save([app.executableFolder, '\Settings\classes.mat'], 'classes');
                update_classes_listbox(app);
            end
        end

        % Button pushed function: AdjustContrastButton
        function AdjustContrastButtonPushed(app, event)
            %app.hTool = imadjust(app.hImage);
            update_image(app);
            max_int = app.minmaxintensitySpinner.Value/100;
            min_int = app.MinintensitySpinner.Value/100;
            
            %This isn't actually doing anything. All of the contrast change
            %is being done in update_image --> update_image_plane
            imshow(imadjust(app.img_fstack, [min_int max_int]), 'Parent',app.Processed_ImageAxes);
        end

        % Button pushed function: ProcessImageButton
        function ProcessImageButtonPushed(app, event)
            disp('Need to add functionality that process the image with the given function and displays it onto the processed image tab')
            try

                app.processed_image_handle = process_image(app, app.image_handle, app.ProcessingFunctionsDropDown.Value);
                imshow(app.processed_image_handle, 'Parent',app.Processed_ImageAxes);
            catch ME
                errorMessage = sprintf('Error in function %s() at line %d.\n\nError Message: Error Importing image folder', ...
                ME.stack(1).name, ME.stack(1).line);
                uiwait(warndlg(errorMessage), 5);
            end
            % the processing cuntions need to follow this logic 
            % (1) process the current image (z stack or single plane)
            % (2) tell the user that hte processing is occuring or update
            % when teh processing is finished
            % (3) return final resulting image the the GUI and update
            % processing axes


            % all of this can be done automatically when you load an image
            % with the "Auto-Processing" check box in the settings tab
        end

        % Callback function
        function MultiplePlanesButtonValueChanged(app, event)
            value = app.MultiplePlanesButton.Value;
            disp(value)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.grid_idx = [floor(app.UIAxes.CurrentPoint(1,1)),floor(app.UIAxes.CurrentPoint(1,2))];
            update_grid_FOV(app);
            update_grid_FOV_processed(app);
        end

        % Value changed function: WindowOverlapEditField, 
        % ...and 1 other component
        function WindowSizeEditFieldValueChanged(app, event)
            update_grid_annotation(app);
            update_grid_processed_annotation(app);
        end

        % Value changed function: DLModelPredictionsCheckBox
        function DLModelPredictionsCheckBoxValueChanged(app, event)
            on = app.DLModelPredictionsCheckBox.Value;
            if on
                load_annotation(app, app.filename_annotations);
                app.load_annotations_sentinel = true;
                draw_all(app);
            else
                app.load_annotations_sentinel = false;
                reset_DL_annotations(app);
                draw_all(app);
            end
        end

        % Value changed function: GroundTruthCheckBox
        function GroundTruthCheckBoxValueChanged(app, event)
            on = app.GroundTruthCheckBox.Value;
            if on
%                 disp("CHECK BOX CHANGED TO ON!");
                load_gTruth(app, app.filename_gTruth);
                app.load_gTruth_sentinel = true;
                draw_all(app);
            else
%                 disp("CHECK BOX CHANGED TO OFFFFF!");
                app.load_gTruth_sentinel = false;
                reset_gTruth_annotations(app);
                draw_all(app);
                app.NumDefectsGTLabel.Text = "";
            end
        end

        % Value changed function: MinintensitySpinner
        function MinintensitySpinnerValueChanged(app, event)
            value = app.MinintensitySpinner.Value;
            update_image(app);
        end

        % Value changed function: ConfidenceField
        function ConfidenceFieldValueChanged(app, event)
            value = app.ConfidenceField.Value;
            draw_all(app);
        end

        % Button pushed function: NextdButton
        function NextdButtonPushed(app, event)
            % increase the index of the next cropped defect to show
            if (app.defect_idx==length(app.save_array_bb))
                app.Label.Text = 'No more defects';
            else
                app.defect_idx = app.defect_idx + 1;
                app.Label.Text = sprintf('%d of %d', app.defect_idx, length(app.save_array_bb));
            end
            z = app.z_plane_arr(app.defect_idx);
            app.defect_z = z;
            update_defect_viewer(app, z);
        end

        % Button pushed function: PreviousaButton
        function PreviousaButtonPushed(app, event)
            % decrease the index of the next cropped defect to show
            if (app.defect_idx==1)
                app.Label.Text = 'No more defects';
            else
                app.defect_idx = app.defect_idx - 1;
                app.Label.Text = sprintf('%d of %d', app.defect_idx, length(app.save_array_bb));
            end
            z = app.z_plane_arr(app.defect_idx);
            app.defect_z = z;
            update_defect_viewer(app, z);
        end

        % Button pushed function: RemovedefectrButton
        function RemovedefectrButtonPushed(app, event)
            app.save_array_bb(:,app.defect_idx) = [];
            app.color_arr(:,app.defect_idx) = []; %append our current color for our bounding box
            app.z_plane_arr(app.defect_idx) = [];
            app.classname_arr(app.defect_idx) = [];
            app.conf_arr(app.defect_idx) = [];
            cla(app.ImageAxes);
            update_image(app);
            draw_all(app);

            % show current defect
            if (app.defect_idx==length(app.save_array_bb))
                app.Label.Text = 'No more defects';
            else
                app.defect_idx = app.defect_idx;
                app.Label.Text = sprintf('%d of %d', app.defect_idx, length(app.save_array_bb));
            end
            z = app.z_plane_arr(app.defect_idx);
            app.defect_z = z;
            update_defect_viewer(app, z);
        end

        % Button pushed function: UpzwButton
        function UpzwButtonPushed(app, event)
            %move to the plane upward for current defect
            if (app.z_plane_arr(app.defect_idx) == size(app.image_handle,4))
                app.Label.Text = 'Maximum plane';
                app.defect_z = size(app.image_handle,4);
            else
                app.Label.Text = sprintf('%d of %d', app.defect_idx, length(app.save_array_bb));
                app.defect_z = app.defect_z + 1;
            end
            update_defect_viewer(app, app.defect_z);
 
        end

        % Button pushed function: DownzsButton
        function DownzsButtonPushed(app, event)
            %move to the plane down for current defect
            if (app.z_plane_arr(app.defect_idx) == 1)
                app.Label.Text = 'Minimum plane';
                app.defect_z = 1;
            else
                app.Label.Text = sprintf('%d of %d', app.defect_idx, length(app.save_array_bb));
                app.defect_z = app.defect_z - 1;
            end
            update_defect_viewer(app, app.defect_z);
        end

        % Button pushed function: MakebgbButton
        function MakebgbButtonPushed(app, event)
            app.classname_arr(app.defect_idx) = "Background ";
            app.color_arr(:,app.defect_idx) = [1; 1; 0];
            update_defect_viewer(app, app.defect_z);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
        % Find bounding boxes below confidence threshold
        ind_conf = find(app.conf_arr < app.ConfidenceField.Value);
        
        % If there are items to remove
        if ~isempty(ind_conf)
            % Remove all bounding boxes below confidence threshold at once
            app.save_array_bb(:, ind_conf) = [];
            app.color_arr(:, ind_conf) = [];
            app.z_plane_arr(ind_conf) = [];
            app.classname_arr(ind_conf) = [];
            app.conf_arr(ind_conf) = [];
            
            % Update display
            cla(app.ImageAxes);
            update_image(app);
            draw_all(app);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileIOPanel = uipanel(app.UIFigure);
            app.FileIOPanel.Title = 'File I/O';
            app.FinishSaveButton = uibutton(app.FileIOPanel, 'push');
            app.FinishSaveButton.ButtonPushedFcn = createCallbackFcn(app, @FinishSaveButtonPushed, true);
            app.FinishSaveButton.Text = 'Finish & Save';
            app.SaveFileFormatLabel = uilabel(app.FileIOPanel);
            app.SaveFileFormatLabel.Text = 'Save File Format:';
            app.SaveFileFormatDropDown = uidropdown(app.FileIOPanel);
            app.SaveFileFormatDropDown.Items = {'.mat', 'Table', 'Text', 'JSON'};
            app.SaveFileFormatDropDown.Value = '.mat';
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Visible = 'off';
            app.SliceSliderLabel.Text = 'Slice #';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.Limits = [0 15];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9];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
            app.SliceSlider.Visible = 'off';
            app.MaxintensityLabel = uilabel(app.UIFigure);
            app.MaxintensityLabel.Text = 'min-max intensity (%)';
            app.minmaxintensitySpinner = uispinner(app.UIFigure);
            app.minmaxintensitySpinner.RoundFractionalValues = 'on';
            app.minmaxintensitySpinner.ValueDisplayFormat = '%.0f';
            app.minmaxintensitySpinner.ValueChangedFcn = createCallbackFcn(app, @minmaxintensitySpinnerValueChanged, true);
            app.minmaxintensitySpinner.Value = 100;
            app.ImportFolderButton = uibutton(app.UIFigure, 'push');
            app.ImportFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFolderButtonPushed, true);
            app.ImportFolderButton.Text = 'Import Folder';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Enable = 'off';
            app.PreviousButton.Text = 'Previous';
            app.HideAllRectanglesSwitchLabel = uilabel(app.UIFigure);
            app.HideAllRectanglesSwitchLabel.Text = 'Hide All Rectangles';
            app.HideAllRectanglesSwitch = uiswitch(app.UIFigure, 'slider');
            app.HideAllRectanglesSwitch.ValueChangedFcn = createCallbackFcn(app, @HideAllRectanglesSwitchValueChanged, true);
            app.RandomizeImagefolderSwitchLabel = uilabel(app.UIFigure);
            app.RandomizeImagefolderSwitchLabel.Text = 'Randomize Image folder';
            app.RandomizeImagefolderSwitch = uiswitch(app.UIFigure, 'slider');
            app.ZoomButton = uibutton(app.UIFigure, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Tooltip = {'Hold down ''Ctrl'' to zoom in on image'};
            app.ZoomButton.Text = 'Zoom';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.ClassesTab = uitab(app.TabGroup2);
            app.ClassesTab.Title = 'Classes';
            app.CurrentClassLabel = uilabel(app.ClassesTab);
            app.CurrentClassLabel.Text = 'Current Class:';
            app.CurrentClassLbl = uilabel(app.ClassesTab);
            app.CurrentClassLbl.Text = 'Name: ''Default'' - Shortcut: ''1''';
            app.ClassnamesListBoxLabel = uilabel(app.ClassesTab);
            app.ClassnamesListBoxLabel.Text = 'Class names ';
            app.ClassnamesListBox = uilistbox(app.ClassesTab);
            app.ClassnamesListBox.Items = {'Default | Shortcut: ''1'''};
            app.ClassnamesListBox.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.ClassnamesListBox.ValueChangedFcn = createCallbackFcn(app, @ClassnamesListBoxValueChanged, true);
            app.ClassnamesListBox.Value = 1;
            app.AddClassButton = uibutton(app.ClassesTab, 'push');
            app.AddClassButton.ButtonPushedFcn = createCallbackFcn(app, @AddClassButtonPushed, true);
            app.AddClassButton.Text = 'Add Class';
            app.ClassnameLabel = uilabel(app.ClassesTab);
            app.ClassnameLabel.Text = 'Classname:';
            app.ClassnameEditField = uieditfield(app.ClassesTab, 'text');
            app.ColorSelectorBtn = uibutton(app.ClassesTab, 'push');
            app.ColorSelectorBtn.ButtonPushedFcn = createCallbackFcn(app, @ColorSelectorBtnPushed, true);
            app.ColorSelectorBtn.Text = 'Class Color';
            app.ClassshapeLabel = uilabel(app.ClassesTab);
            app.ClassshapeLabel.Text = 'Class shape:';
            app.ClassshapeDropDown = uidropdown(app.ClassesTab);
            app.ClassshapeDropDown.Items = {'Rectangle', 'Polygon', 'Line'};
            app.ClassshapeDropDown.Value = 'Rectangle';
            app.ShortcutLabel = uilabel(app.ClassesTab);
            app.ShortcutLabel.Text = 'Shortcut:';
            app.ShortcutEditField = uieditfield(app.ClassesTab, 'text');
            app.ShortcutEditField.ValueChangedFcn = createCallbackFcn(app, @ShortcutEditFieldValueChanged, true);
            app.EditCurrentClassButton = uibutton(app.ClassesTab, 'push');
            app.EditCurrentClassButton.ButtonPushedFcn = createCallbackFcn(app, @EditCurrentClassButtonPushed, true);
            app.EditCurrentClassButton.Text = 'Edit Current Class';
            app.DeleteCurrentClassButton = uibutton(app.ClassesTab, 'push');
            app.DeleteCurrentClassButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCurrentClassButtonPushed, true);
            app.DeleteCurrentClassButton.Text = 'Delete Current Class';
            app.Layers = uitab(app.TabGroup2);
            app.Layers.Title = 'Layers';
            app.LayersLabel = uilabel(app.Layers);
            app.LayersLabel.Text = {'Annotation Layers'; ''};
            app.GroundTruthCheckBox = uicheckbox(app.Layers);
            app.GroundTruthCheckBox.ValueChangedFcn = createCallbackFcn(app, @GroundTruthCheckBoxValueChanged, true);
            app.GroundTruthCheckBox.Text = 'Ground Truth';
            app.DLModelPredictionsCheckBox = uicheckbox(app.Layers);
            app.DLModelPredictionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DLModelPredictionsCheckBoxValueChanged, true);
            app.DLModelPredictionsCheckBox.Text = 'DL Model Predictions';
            app.NumDefectsGTLabel = uilabel(app.Layers);
            app.NumDefectsGTLabel.Text = '';
            app.NumDefectsAnnLabel = uilabel(app.Layers);
            app.NumDefectsAnnLabel.Text = '';
            app.NumDefectsLabel = uilabel(app.Layers);
            app.NumDefectsLabel.Text = 'Num Defects';
            app.FilenameStemLabel = uilabel(app.Layers);
            app.FilenameStemLabel.Text = '';
            app.TransparencyLabel_2 = uilabel(app.Layers);
            app.TransparencyLabel_2.Text = 'Text transparency';
            app.TextTransparencyField = uieditfield(app.Layers, 'numeric');
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.ScrollSpeed110Label = uilabel(app.SettingsTab);
            app.ScrollSpeed110Label.Text = 'Scroll Speed (1-10)';
            app.ScrollSpeedEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ScrollSpeedEditField.Limits = [1 10];
            app.ScrollSpeedEditField.RoundFractionalValues = 'on';
            app.ScrollSpeedEditField.Value = 1;
            app.AnnotatorNameEditFieldLabel = uilabel(app.SettingsTab);
            app.AnnotatorNameEditFieldLabel.Text = 'Annotator Name';
            app.AnnotatorNameEditField = uieditfield(app.SettingsTab, 'text');
            app.AnnotatorNameEditField.Value = 'Default';
            app.MinObjectSizepixelsEditField_2Label = uilabel(app.SettingsTab);
            app.MinObjectSizepixelsEditField_2Label.Text = 'Min. Object Size (pixels)';
            app.MinObjectSizepixelsEditField = uieditfield(app.SettingsTab, 'numeric');
            app.MinObjectSizepixelsEditField.Limits = [0 Inf];
            app.MinObjectSizepixelsEditField.Value = 30;
            app.SaveSettingsButton = uibutton(app.SettingsTab, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.DeepLearningModelDropDownLabel = uilabel(app.SettingsTab);
            app.DeepLearningModelDropDownLabel.Text = 'Deep Learning Model';
            app.DeepLearningModelDropDown = uidropdown(app.SettingsTab);
            app.DeepLearningModelDropDown.Items = {'None'};
            app.DeepLearningModelDropDown.Value = 'None';
            app.ImageListDropDownLabel = uilabel(app.SettingsTab);
            app.ImageListDropDownLabel.Enable = 'off';
            app.ImageListDropDownLabel.Text = 'Image List';
            app.ImageListDropDown = uidropdown(app.SettingsTab);
            app.ImageListDropDown.Items = {'All', 'Un-Annotated', 'Annotated'};
            app.ImageListDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageListDropDownValueChanged, true);
            app.ImageListDropDown.Enable = 'off';
            app.ImageListDropDown.Value = 'All';
            app.IncludeSubfoldersCheckBox = uicheckbox(app.SettingsTab);
            app.IncludeSubfoldersCheckBox.Text = 'Include Subfolders';
            app.AutoProcessImagesCheckBox = uicheckbox(app.SettingsTab);
            app.AutoProcessImagesCheckBox.Text = 'Auto-Process Images';
            app.ProcessingFunctionsDropDownLabel = uilabel(app.SettingsTab);
            app.ProcessingFunctionsDropDownLabel.Text = 'Processing Functions';
            app.ProcessingFunctionsDropDown = uidropdown(app.SettingsTab);
            app.ProcessingFunctionsDropDown.Items = {'None'};
            app.ProcessingFunctionsDropDown.Value = 'None';
            app.GridAnnotationCheckBox = uicheckbox(app.SettingsTab);
            app.GridAnnotationCheckBox.Text = 'Grid Annotation';
            app.GridAnnotationTab = uitab(app.TabGroup2);
            app.GridAnnotationTab.Title = 'Grid Annotation';
            app.UIAxes = uiaxes(app.GridAnnotationTab);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 10];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.WindowSizeEditFieldLabel = uilabel(app.GridAnnotationTab);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.GridAnnotationTab, 'numeric');
            app.WindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSizeEditFieldValueChanged, true);
            app.WindowSizeEditField.Value = 600;
            app.WindowOverlapLabel = uilabel(app.GridAnnotationTab);
            app.WindowOverlapLabel.Text = 'Window Overlap (%)';
            app.WindowOverlapEditField = uieditfield(app.GridAnnotationTab, 'numeric');
            app.WindowOverlapEditField.Limits = [-500 500];
            app.WindowOverlapEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSizeEditFieldValueChanged, true);
            app.WindowOverlapEditField.Value = 10;
            app.Status = uilabel(app.UIFigure);
            app.Status.Text = '';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup3);
            app.MainTab.Title = 'Main';
            app.ImageAxes = uiaxes(app.MainTab);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.Visible = 'off';
            app.ProcessedImageTab = uitab(app.TabGroup3);
            app.ProcessedImageTab.Title = 'Processed Image';
            app.Processed_ImageAxes = uiaxes(app.ProcessedImageTab);
            app.Processed_ImageAxes.XTick = [];
            app.Processed_ImageAxes.YTick = [];
            app.DefectViewerTab = uitab(app.TabGroup3);
            app.DefectViewerTab.Title = 'Defect Viewer';
            app.Cropped_Defect_ImageAxes = uiaxes(app.DefectViewerTab);
            app.Cropped_Defect_ImageAxes.XTick = [];
            app.Cropped_Defect_ImageAxes.YTick = [];
            app.NextdButton = uibutton(app.DefectViewerTab, 'push');
            app.NextdButton.ButtonPushedFcn = createCallbackFcn(app, @NextdButtonPushed, true);
            app.NextdButton.Text = 'Next (d)';
            app.PreviousaButton = uibutton(app.DefectViewerTab, 'push');
            app.PreviousaButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousaButtonPushed, true);
            app.PreviousaButton.Text = 'Previous (a)';
            app.Label = uilabel(app.DefectViewerTab);
            app.Label.Text = '1';
            app.RemovedefectrButton = uibutton(app.DefectViewerTab, 'push');
            app.RemovedefectrButton.ButtonPushedFcn = createCallbackFcn(app, @RemovedefectrButtonPushed, true);
            app.RemovedefectrButton.Text = 'Remove defect (r)';
            app.UpzwButton = uibutton(app.DefectViewerTab, 'push');
            app.UpzwButton.ButtonPushedFcn = createCallbackFcn(app, @UpzwButtonPushed, true);
            app.UpzwButton.Text = 'Up z (w)';
            app.DownzsButton = uibutton(app.DefectViewerTab, 'push');
            app.DownzsButton.ButtonPushedFcn = createCallbackFcn(app, @DownzsButtonPushed, true);
            app.DownzsButton.Text = 'Down z (s)';
            app.MakebgbButton = uibutton(app.DefectViewerTab, 'push');
            app.MakebgbButton.ButtonPushedFcn = createCallbackFcn(app, @MakebgbButtonPushed, true);
            app.MakebgbButton.Text = 'Make bg (b)';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.AdjustContrastButton = uibutton(app.UIFigure, 'push');
            app.AdjustContrastButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustContrastButtonPushed, true);
            app.AdjustContrastButton.Text = 'Adjust Contrast';
            app.ProcessImageButton = uibutton(app.UIFigure, 'push');
            app.ProcessImageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessImageButtonPushed, true);
            app.ProcessImageButton.Text = 'Process Image';
            app.PressSpacetoreturntofullFOVLabel = uilabel(app.UIFigure);
            app.PressSpacetoreturntofullFOVLabel.Text = 'Press ''Space'' to return to full FOV';
            app.MinintensitySpinner = uispinner(app.UIFigure);
            app.MinintensitySpinner.RoundFractionalValues = 'on';
            app.MinintensitySpinner.ValueDisplayFormat = '%.0f';
            app.MinintensitySpinner.ValueChangedFcn = createCallbackFcn(app, @MinintensitySpinnerValueChanged, true);
            app.ConfidenceEditFieldLabel = uilabel(app.UIFigure);
            app.ConfidenceEditFieldLabel.Text = 'Confidence';
            app.ConfidenceField = uieditfield(app.UIFigure, 'numeric');
            app.ConfidenceField.ValueChangedFcn = createCallbackFcn(app, @ConfidenceFieldValueChanged, true);
            app.ConfidenceField.Value = 0.1;
            app.TransparencyLabel = uilabel(app.UIFigure);
            app.TransparencyLabel.Text = 'Transparency';
            app.TransparencyField = uieditfield(app.UIFigure, 'numeric');
            app.TransparencyField.Value = 1;
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Myelin_defect_annotation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end